classdef MemristorRawDataDealWith < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OpenDataMenu            matlab.ui.container.Menu
        SaveDataMenu            matlab.ui.container.Menu
        ExportDataMenu          matlab.ui.container.Menu
        SelectFolderMenu        matlab.ui.container.Menu
        SWEEPMenu               matlab.ui.container.Menu
        IVMenu                  matlab.ui.container.Menu
        DiscontinuityMenu       matlab.ui.container.Menu
        PULSEMenu               matlab.ui.container.Menu
        TIMEMenu                matlab.ui.container.Menu
        CurrentRevise           matlab.ui.control.NumericEditField
        EditFieldLabel          matlab.ui.control.Label
        DataEditing             matlab.ui.container.Panel
        DataFilesEditField      matlab.ui.control.EditField
        DataFilesLabel          matlab.ui.control.Label
        DiscontinuityEditField  matlab.ui.control.NumericEditField
        UIDiscontinuityLabel    matlab.ui.control.Label
        DeleteButton            matlab.ui.control.Button
        AddButton               matlab.ui.control.Button
        ModifyButton            matlab.ui.control.Button
        DrawingControl          matlab.ui.container.Panel
        AmountOfFileEditField   matlab.ui.control.NumericEditField
        AmountOfFileLabel       matlab.ui.control.Label
        TypeDropDown            matlab.ui.control.DropDown
        TypeLabel               matlab.ui.control.Label
        ProcessDropDown         matlab.ui.control.DropDown
        ProcessLabel            matlab.ui.control.Label
        YAxisTypeDropDown       matlab.ui.control.DropDown
        YAxisTypeLabel          matlab.ui.control.Label
        StartSpinner            matlab.ui.control.Spinner
        StartLabel              matlab.ui.control.Label
        StopSpinner             matlab.ui.control.Spinner
        StopLabel               matlab.ui.control.Label
        SingleDataCheckBox      matlab.ui.control.CheckBox
        DoubleDataCheckBox      matlab.ui.control.CheckBox
        DestinationFolderLabel  matlab.ui.control.Label
        ExportDataFolderLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        UIAxesContextMenu       matlab.ui.container.ContextMenu
        SETMenu                 matlab.ui.container.Menu
        RESETMenu               matlab.ui.container.Menu
    end


    properties (Access = private)
        Data
        DrawingNumber
        ExportDataFolder
        LineProperties
    end

    methods (Access = private)

        function status = FunOpenData(app)
            if exist([app.DestinationFolderLabel.Text,'\Data.mat'],'file')
                try
                    load([app.DestinationFolderLabel.Text,'\Data.mat'],'Data');
                    app.Data = Data; %#ok<*ADPROP>
                    status.status = true;
                catch
                    status.message = '存在旧版本 Data.mat 文件或文件以损坏';
                    status.status = false;
                end
            else
                try
                    app.Data = dir([app.DestinationFolderLabel.Text,'\*.csv']);
                    app.Data = rmfield(app.Data,["date","isdir","datenum"]);
                    for ii = 1:length(app.Data)
                        if contains(app.Data(ii).name,"-PSET")
                            app.Data(ii).type = 'PULSE';
                            app.Data(ii).Process = 'SET';
                        elseif contains(app.Data(ii).name,"-PRESET")
                            app.Data(ii).type = 'PULSE';
                            app.Data(ii).Process = 'RESET';
                        elseif contains(app.Data(ii).name,"-SET")
                            app.Data(ii).type = 'SWEEP';
                            app.Data(ii).Process = 'SET';
                        elseif contains(app.Data(ii).name,"-RESET")
                            app.Data(ii).type = 'SWEEP';
                            app.Data(ii).Process = 'RESET';
                        else
                            app.Data(ii).type = 'TIME';
                            app.Data(ii).Process = 'TIME';
                        end
                        app.Data(ii).date = datetime(app.Data(ii).name(1:15),'InputFormat','yyyyMMdd-HHmmss');
                        cache = load([app.Data(ii).folder,'\',app.Data(ii).name]);
                        app.Data(ii).Time = cache(:,1);
                        app.Data(ii).Voltage = cache(:,2);
                        app.Data(ii).Current = cache(:,3);
                        if contains(app.Data(ii).type,"SWEEP")
                            [~,app.Data(ii).Discontinuity] = max(abs(diff(cache(:,3))));
                        else
                            app.Data(ii).Discontinuity = 0;
                        end
                        app.Data(ii).Delete = false;
                    end
                    if isempty(app.Data)
                        status.message = '所选文件夹不包含兼容数据';
                        status.status = false;
                    else
                        status.status = true;
                        app.SaveDataMenu.Enable = 'on';
                    end
                catch
                    status.message = '数据读取存在错误';
                    status.status = false;
                end
            end
            FunDrawing(app)
        end

        function FunDrawing(app)
            app.CurrentRevise.Value = 5e-8;
            app.UIAxes.NextPlot = 'add';
            DrawingNumber = find(categorical({app.Data.type}') == 'SWEEP'); % 创建 SWEEP 数据 IV Line 属性
            app.LineProperties.SWEEP = DrawingNumber;
            app.LineProperties.VCabs(DrawingNumber) = arrayfun(@(x) ...
                plot(app.UIAxes,x.Voltage,abs(x.Current + app.CurrentRevise.Value),'Color','#D0D0D0'),app.Data(DrawingNumber));
            set(app.LineProperties.VCabs(DrawingNumber),{'Visible'},{'off'})
            app.LineProperties.VCnoabs(DrawingNumber) = arrayfun(@(x) ...
                plot(app.UIAxes,x.Voltage,x.Current + app.CurrentRevise.Value,'Color','#D0D0D0'),app.Data(DrawingNumber));
            set(app.LineProperties.VCnoabs(DrawingNumber),{'Visible'},{'off'})
            app.LineProperties.Discontinuityabs(DrawingNumber) = arrayfun(@(x) ...
                plot(app.UIAxes,x.Voltage(x.Discontinuity),abs(x.Current(x.Discontinuity)),'LineStyle','none','Marker','o','MarkerEdgeColor','r'),app.Data(DrawingNumber));
            set(app.LineProperties.Discontinuityabs(DrawingNumber),{'Visible'},{'off'})
            app.LineProperties.Discontinuity(DrawingNumber) = arrayfun(@(x) ...
                plot(app.UIAxes,x.Voltage(x.Discontinuity),x.Current(x.Discontinuity),'LineStyle','none','Marker','o','MarkerEdgeColor','r'),app.Data(DrawingNumber));
            set(app.LineProperties.Discontinuity(DrawingNumber),{'Visible'},{'off'})
            DrawingNumber = find(categorical({app.Data.type}') ~= 'SWEEP'); % PULSE TIME
            app.LineProperties.unSWEEP = DrawingNumber;
            app.LineProperties.TV(DrawingNumber) = arrayfun(@(x) ...
                plot(app.UIAxes,x.Time,x.Voltage,'k'),app.Data(DrawingNumber));
            set(app.LineProperties.TV(DrawingNumber),{'Visible'},{'off'})
            yyaxis(app.UIAxes,'right')
            app.LineProperties.TCabs(DrawingNumber) = arrayfun(@(x) ...
                plot(app.UIAxes,x.Time,abs(x.Current + app.CurrentRevise.Value),'r'),app.Data(DrawingNumber));
            set(app.LineProperties.TCabs(DrawingNumber),{'Visible'},{'off'})
            app.LineProperties.TCnoabs(DrawingNumber) = arrayfun(@(x) ...
                plot(app.UIAxes,x.Voltage,x.Current + app.CurrentRevise.Value,'r'),app.Data(DrawingNumber));
            set(app.LineProperties.TCnoabs(DrawingNumber),{'Visible'},{'off'})
            yyaxis(app.UIAxes,'left')
        end

        function FunSaveData(app)
            Data = app.Data;
            save([app.DestinationFolderLabel.Text,'\Data.mat'],'Data')
            app.SaveDataMenu.Enable = 'off';
        end

        function FunEditLineObjects(app)
            app.SWEEPMenu.Visible = 'off';
            app.PULSEMenu.Visible = 'off';
            app.TIMEMenu.Visible = 'off';
            FunDrawingParameterCheck(app)
            Color = {'#0072BD','#D95319','#EDB120','#7E2F8E','#77AC30','#4DBEEE','#A2142F'};
            if ~isempty(app.DrawingNumber)
                if length(app.DrawingNumber) == 1
                    app.DataFilesEditField.Value = app.Data(app.DrawingNumber(1)).name;
                    app.DiscontinuityEditField.Value = (app.Data(app.DrawingNumber(1)).Discontinuity - 1) * app.Data(app.DrawingNumber(1)).Voltage(2);
                else
                    app.DataFilesEditField.Value = '';
                    app.DiscontinuityEditField.Value = 0;
                end
                switch app.TypeDropDown.Value
                    case 'SWEEP'
                        app.SWEEPMenu.Visible = app.ExportDataFolder;
                        switch app.YAxisTypeDropDown.Value
                            case 'Log'
                                app.UIAxes.YScale = "Log";
                                set(app.LineProperties.VCabs(app.LineProperties.SWEEP),{'Visible'},{'off'})
                                set(app.LineProperties.VCabs(app.DrawingNumber),{'Visible','Color'},{'on',Color{1}})
                            case 'Linear'
                                app.UIAxes.YScale = "Linear";
                                set(app.LineProperties.VCabs(app.LineProperties.SWEEP),{'Visible'},{'off'})
                                set(app.LineProperties.VCabs(app.DrawingNumber),{'Visible','Color'},{'on',Color{1}})
                        end
                    case 'PULSE'
                        app.PULSEMenu.Visible = app.ExportDataFolder;
                        timestop = 0;
                        for ii = app.DrawingNumber
%                             FunDrawingXaxisIsTime(app,app.Data(ii).Time + timestop,app.Data(ii).Voltage,app.Data(ii).Current)
                            timestop = timestop + app.Data(ii).Time(end) + mean(diff(app.Data(ii).Time));
                            if ii == app.DrawingNumber(1)
                                hold(app.UIAxes,'on')
                            end
                        end
                        hold(app.UIAxes,'off')
                    case 'TIME'
                        app.TIMEMenu.Visible = app.ExportDataFolder;
                        ii = app.DrawingNumber;
                        switch app.YAxisTypeDropDown.Value
                            case 'Log'
%                                 semilogy(app.UIAxes,app.Data(ii).Voltage,abs(app.Data(ii).Current),'-')
                            case 'Linear'
%                                 plot(app.UIAxes,app.Data(ii).Voltage,app.Data(ii).Current,'-')
                        end
                    case 'Delete'
                        ii = app.DrawingNumber;
                        switch app.Data(ii).type
                            case 'SWEEP'
                                switch app.YAxisTypeDropDown.Value
                                    case 'Log'
%                                         semilogy(app.UIAxes,app.Data(ii).Voltage,abs(app.Data(ii).Current),'-')
                                    case 'Linear'
%                                         plot(app.UIAxes,app.Data(ii).Voltage,app.Data(ii).Current,'-')
                                end
                            otherwise
%                                 FunDrawingXaxisIsTime(app,app.Data(ii).Time,app.Data(ii).Voltage,app.Data(ii).Current)
                        end
                end
            else
                app.DataFilesEditField.Value = '';
                app.DiscontinuityEditField.Value = 0;
            end
        end

        function FunDrawingParameterCheck(app)
            if categorical({app.TypeDropDown.Value}) == "Delete"
                app.DrawingNumber = find([app.Data.Delete]);
            else
                logicaltype = categorical({app.Data.type}) == app.TypeDropDown.Value;
                logicalProcess = categorical({app.Data.Process}) == app.ProcessDropDown.Value ...
                    | (categorical({app.ProcessDropDown.Value}) == 'ALL');
                app.DrawingNumber = find((logicaltype & logicalProcess) - ([app.Data.Delete] & logicaltype & logicalProcess));
            end
            jj = length(app.DrawingNumber);
            app.AmountOfFileEditField.Value = jj;
            if jj > 0
                if app.SingleDataCheckBox.Value
                    if app.StartSpinner.Value > jj
                        app.StartSpinner.Value = jj;
                    end
                    app.StopSpinner.Value = app.StartSpinner.Value;
                    app.DrawingNumber = app.DrawingNumber(app.StartSpinner.Value);
                elseif app.DoubleDataCheckBox.Value
                    if app.StartSpinner.Value + 1 > jj
                        app.StartSpinner.Value = app.StartSpinner.Value - 2;
                    end
                    app.StopSpinner.Value = app.StartSpinner.Value + 1;
                    app.DrawingNumber = app.DrawingNumber(app.StartSpinner.Value:app.StopSpinner.Value);
                else
                    if app.StartSpinner.Value > jj
                        app.StartSpinner.Value = jj;
                    end
                    if app.StopSpinner.Value > jj
                        app.StopSpinner.Value = jj;
                    end
                    app.DrawingNumber = app.DrawingNumber(app.StartSpinner.Value:app.StopSpinner.Value);
                end
            end
        end

        function Data = FunDataInterp(app, Index, Process)
            Voltage = max(abs(arrayfun(@(S) S.Voltage(round(end/2)),app.Data(Index))));
            Voltage_Change = min(abs(arrayfun(@(x) mean(abs(diff(x.Voltage))),app.Data(Index))));
            Voltage = [0:Voltage_Change:Voltage (Voltage - Voltage_Change):-Voltage_Change:0]' * Process;
            I1 = arrayfun(@(S) interp1(S.Voltage(1:round(end/2)),S.Current(1:round(end/2)), ...
                Voltage(1:round(end/2))),app.Data(Index),'UniformOutput',false);
            I2 = arrayfun(@(S) interp1(S.Voltage(round(end/2)+1:end),S.Current(round(end/2)+1:end), ...
                Voltage(round(end/2)+1:end)),app.Data(Index),'UniformOutput',false);
            Data = [Voltage [I1{:}; I2{:}]];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenDataMenu
        function OpenDataMenuMenuSelected(app, event)
            foldername = uigetdir('C:\Users\Public\Documents\');
            if foldername ~= 0
                app.DestinationFolderLabel.Text = foldername;
                status = FunOpenData(app);
                if status.status
                    app.UIAxes.Visible = 'on';
                    app.ExportDataMenu.Enable = 'on';
                    app.DataEditing.Visible = 'on';
                    app.DrawingControl.Visible = 'on';
                    app.ExportDataFolder = 'off';
                    FunEditLineObjects(app)
                else
                    uiconfirm(app.UIFigure,status.message,'请检查数据','Icon','warning','Options',{'OK'})
                end
            end
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuMenuSelected(app, event)
            FunSaveData(app)
        end

        % Menu selected function: SelectFolderMenu
        function SelectFolderMenuMenuSelected(app, event)
            foldername = uigetdir('C:\Users\Public\Documents\');
            if foldername ~= 0
                app.ExportDataFolderLabel.Text = foldername;
                app.ExportDataFolder = 'on';
                FunEditLineObjects(app)
            end
        end

        % Menu selected function: IVMenu
        function IVMenuMenuSelected(app, event)
            SET = app.DrawingNumber(find(categorical({app.Data(app.DrawingNumber).Process}) == "SET"));
            RESET = app.DrawingNumber(find(categorical({app.Data(app.DrawingNumber).Process}) == "RESET"));
            if length(SET) == length(RESET)
                SET = FunDataInterp(app,SET,1);
                RESET = FunDataInterp(app,RESET,-1);
                cache = [SET; RESET];
                Options = uiconfirm(app.UIFigure,'对所有电流值取绝对值？','确认','Options',{'Yes','No'});
                if categorical({Options}) == "Yes"
                    cache(:,2:end) = abs(cache(:,2:end));
                end
                writematrix(cache,[app.ExportDataFolderLabel.Text,'\IVSweep.csv'])
            else
                uiconfirm(app.UIFigure,'SET RESET 数据数量不同！','请检查数据','Icon','warning','Options',{'OK'})
            end
        end

        % Menu selected function: DiscontinuityMenu
        function DiscontinuityMenuMenuSelected(app, event)
            cache = ([app.Data(app.DrawingNumber).Discontinuity] - 1)' .* ...
                arrayfun(@(x) mean(abs(diff(x.Voltage))),app.Data(app.DrawingNumber));
            writematrix(cache,[app.ExportDataFolderLabel.Text,'\Discontinuity.csv'])
        end

        % Menu selected function: PULSEMenu
        function PULSEMenuSelected(app, event)

        end

        % Menu selected function: TIMEMenu
        function TIMEMenuSelected(app, event)

        end

        % Value changed function: ProcessDropDown, TypeDropDown
        function FilterChanged(app, event)
            if app.DoubleDataCheckBox.Value
                if contains(app.ProcessDropDown.Value,["SET","RESET"])
                    app.ProcessDropDown.Value = 'ALL';
                end
            end
            if contains(app.TypeDropDown.Value,["TIME","Delete"])
                app.DoubleDataCheckBox.Value = false;
                app.SingleDataCheckBox.Value = true;
                if contains(app.ProcessDropDown.Value,["SET","RESET"])
                    app.ProcessDropDown.Value = 'ALL';
                end
            end
            %                     app.StartLabel.Text = 'High';
            %                     app.StopLabel.Text = 'Low';
            %             app.UIAxes.NextPlot = 'replaceall';
            FunEditLineObjects(app)
        end

        % Value changed function: YAxisTypeDropDown
        function YAxisTypeDropDownValueChanged(app, event)
            %             app.UIAxes.NextPlot = 'replace';
            FunEditLineObjects(app)
        end

        % Button pushed function: DeleteButton
        function DeleteButtonButtonPushed(app, event)
            if length(app.DrawingNumber) == 1
                app.Data(app.DrawingNumber).Delete = true;
                app.SaveDataMenu.Enable = 'on';
                FunEditLineObjects(app)
            end
        end

        % Button pushed function: AddButton
        function AddButtonButtonPushed(app, event)
            if length(app.DrawingNumber) == 1
                app.Data(app.DrawingNumber).Delete = false;
                app.SaveDataMenu.Enable = 'on';
                FunEditLineObjects(app)
            end
        end

        % Button pushed function: ModifyButton
        function ModifyButtonButtonPushed(app, event)
            if length(app.DrawingNumber) == 1
                app.Data(app.DrawingNumber).Discontinuity = round(app.DiscontinuityEditField.Value / app.Data(app.DrawingNumber).Voltage(2)) + 1;
                app.SaveDataMenu.Enable = 'on';
                FunEditLineObjects(app)
            end
        end

        % Value changed function: StartSpinner
        function StartSpinnerValueChanged(app, event)
            if app.StartSpinner.Value > app.StopSpinner.Value
                app.StopSpinner.Value = app.StartSpinner.Value;
            end
            FunEditLineObjects(app)
        end

        % Value changed function: StopSpinner
        function StopSpinnerValueChanged(app, event)
            if app.StartSpinner.Value > app.StopSpinner.Value
                app.StartSpinner.Value = app.StopSpinner.Value;
            end
            FunEditLineObjects(app)
        end

        % Value changed function: SingleDataCheckBox
        function SingleDataCheckBoxValueChanged(app, event)
            if ~contains(app.TypeDropDown.Value,["TIME","Delete"])
                if app.SingleDataCheckBox.Value
                    app.StartSpinner.Step = 1;
                    app.DoubleDataCheckBox.Value = false;
                    app.StopSpinner.Value = app.StartSpinner.Value;
                end
                FunEditLineObjects(app)
            else
                app.SingleDataCheckBox.Value = true;
            end
        end

        % Value changed function: DoubleDataCheckBox
        function DoubleDataCheckBoxValueChanged(app, event)
            if  contains(app.TypeDropDown.Value,["SWEEP","PULSE"])
                app.DrawingNumber(1)
                if app.DoubleDataCheckBox.Value && (categorical({app.Data(app.DrawingNumber(1)).Process}) == "SET")
                    app.StartSpinner.Step = 2;
                    app.SingleDataCheckBox.Value = false;
                    FunEditLineObjects(app)
                else
                    app.StartSpinner.Step = 1;
                    app.DoubleDataCheckBox.Value = false;
                end
            else
                app.DoubleDataCheckBox.Value = false;
            end
        end

        % Context menu opening function: UIAxesContextMenu
        function UIAxesContextMenuOpening(app, event)
            app.SETMenu.Text = num2str(sum((categorical({app.Data(app.DrawingNumber).Process}) == "SET")));
            app.RESETMenu.Text = num2str(sum((categorical({app.Data(app.DrawingNumber).Process}) == "RESET")));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if categorical(app.SaveDataMenu.Enable) == "true"
                FunSaveData(app)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Memristor Raw Data Deal With';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenDataMenu = uimenu(app.UIFigure);
            app.OpenDataMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDataMenuMenuSelected, true);
            app.OpenDataMenu.Text = '打开数据';
            app.SaveDataMenu = uimenu(app.UIFigure);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuMenuSelected, true);
            app.SaveDataMenu.Enable = 'off';
            app.SaveDataMenu.Text = '保存数据';
            app.ExportDataMenu = uimenu(app.UIFigure);
            app.ExportDataMenu.Enable = 'off';
            app.ExportDataMenu.Text = '导出数据';
            app.SelectFolderMenu = uimenu(app.ExportDataMenu);
            app.SelectFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectFolderMenuMenuSelected, true);
            app.SelectFolderMenu.Text = '选择导出文件夹';
            app.SWEEPMenu = uimenu(app.ExportDataMenu);
            app.SWEEPMenu.Visible = 'off';
            app.SWEEPMenu.Text = 'SWEEP';
            app.IVMenu = uimenu(app.SWEEPMenu);
            app.IVMenu.MenuSelectedFcn = createCallbackFcn(app, @IVMenuMenuSelected, true);
            app.IVMenu.Text = '电压电流';
            app.DiscontinuityMenu = uimenu(app.SWEEPMenu);
            app.DiscontinuityMenu.MenuSelectedFcn = createCallbackFcn(app, @DiscontinuityMenuMenuSelected, true);
            app.DiscontinuityMenu.Text = '突变电压';
            app.PULSEMenu = uimenu(app.ExportDataMenu);
            app.PULSEMenu.MenuSelectedFcn = createCallbackFcn(app, @PULSEMenuSelected, true);
            app.PULSEMenu.Visible = 'off';
            app.PULSEMenu.Text = 'PULSE';
            app.TIMEMenu = uimenu(app.ExportDataMenu);
            app.TIMEMenu.MenuSelectedFcn = createCallbackFcn(app, @TIMEMenuSelected, true);
            app.TIMEMenu.Visible = 'off';
            app.TIMEMenu.Text = 'TIME';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'V')
            ylabel(app.UIAxes, 'I')
            app.UIAxes.Visible = 'off';
            app.ExportDataFolderLabel = uilabel(app.UIFigure);
            app.ExportDataFolderLabel.Text = '导出数据文件夹';
            app.DestinationFolderLabel = uilabel(app.UIFigure);
            app.DestinationFolderLabel.Text = '目标文件夹';
            app.DrawingControl = uipanel(app.UIFigure);
            app.DrawingControl.Title = '绘图控制';
            app.DrawingControl.Visible = 'off';
            app.DoubleDataCheckBox = uicheckbox(app.DrawingControl);
            app.DoubleDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoubleDataCheckBoxValueChanged, true);
            app.DoubleDataCheckBox.Text = '双数据';
            app.SingleDataCheckBox = uicheckbox(app.DrawingControl);
            app.SingleDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleDataCheckBoxValueChanged, true);
            app.SingleDataCheckBox.Text = '单数据';
            app.SingleDataCheckBox.Value = true;
            app.StopLabel = uilabel(app.DrawingControl);
            app.StopLabel.Text = 'Stop';
            app.StopSpinner = uispinner(app.DrawingControl);
            app.StopSpinner.Limits = [1 Inf];
            app.StopSpinner.ValueChangedFcn = createCallbackFcn(app, @StopSpinnerValueChanged, true);
            app.StopSpinner.Value = 1;
            app.StartLabel = uilabel(app.DrawingControl);
            app.StartLabel.Text = 'Start';
            app.StartSpinner = uispinner(app.DrawingControl);
            app.StartSpinner.Limits = [1 Inf];
            app.StartSpinner.ValueChangedFcn = createCallbackFcn(app, @StartSpinnerValueChanged, true);
            app.StartSpinner.Value = 1;
            app.YAxisTypeLabel = uilabel(app.DrawingControl);
            app.YAxisTypeLabel.Text = 'Y轴刻度模式';
            app.YAxisTypeDropDown = uidropdown(app.DrawingControl);
            app.YAxisTypeDropDown.Items = {'Log', 'Linear'};
            app.YAxisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisTypeDropDownValueChanged, true);
            app.YAxisTypeDropDown.Value = 'Log';
            app.ProcessLabel = uilabel(app.DrawingControl);
            app.ProcessLabel.Text = '过程';
            app.ProcessDropDown = uidropdown(app.DrawingControl);
            app.ProcessDropDown.Items = {'ALL', 'SET', 'RESET'};
            app.ProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterChanged, true);
            app.ProcessDropDown.Value = 'ALL';
            app.TypeLabel = uilabel(app.DrawingControl);
            app.TypeLabel.Text = '数据类型';
            app.TypeDropDown = uidropdown(app.DrawingControl);
            app.TypeDropDown.Items = {'SWEEP', 'PULSE', 'TIME', 'Delete'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterChanged, true);
            app.TypeDropDown.Value = 'SWEEP';
            app.AmountOfFileLabel = uilabel(app.DrawingControl);
            app.AmountOfFileLabel.Text = '数据总量';
            app.AmountOfFileEditField = uieditfield(app.DrawingControl, 'numeric');
            app.DataEditing = uipanel(app.UIFigure);
            app.DataEditing.Title = '数据编辑';
            app.DataEditing.Visible = 'off';
            app.ModifyButton = uibutton(app.DataEditing, 'push');
            app.ModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonButtonPushed, true);
            app.ModifyButton.Text = '更新突变电压';
            app.AddButton = uibutton(app.DataEditing, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.DataEditing, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UIDiscontinuityLabel = uilabel(app.DataEditing);
            app.UIDiscontinuityLabel.Text = '突变电压';
            app.DiscontinuityEditField = uieditfield(app.DataEditing, 'numeric');
            app.DataFilesLabel = uilabel(app.DataEditing);
            app.DataFilesLabel.Text = '数据文件';
            app.DataFilesEditField = uieditfield(app.DataEditing, 'text');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.CurrentRevise = uieditfield(app.UIFigure, 'numeric');
            app.UIAxesContextMenu = uicontextmenu(app.UIFigure);
            app.UIAxesContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @UIAxesContextMenuOpening, true);
            app.SETMenu = uimenu(app.UIAxesContextMenu);
            app.RESETMenu = uimenu(app.UIAxesContextMenu);
            % Assign app.UIAxesContextMenu
            app.UIAxes.ContextMenu = app.UIAxesContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MemristorRawDataDealWith

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end