classdef Memristor_Raw_Data_Deal_With < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ProcessLabel          matlab.ui.control.Label
        Process               matlab.ui.control.DropDown
        StartLabel            matlab.ui.control.Label
        Start                 matlab.ui.control.Spinner
        YAxisTypeLabel        matlab.ui.control.Label
        YAxisType             matlab.ui.control.DropDown
        StopLabel             matlab.ui.control.Label
        Stop                  matlab.ui.control.Spinner
        SingleData            matlab.ui.control.CheckBox
        FolderNameLabel       matlab.ui.control.Label
        FolderName            matlab.ui.control.EditField
        DataFilesLabel        matlab.ui.control.Label
        DataFiles             matlab.ui.control.EditField
        Modify                matlab.ui.control.Button
        Delete                matlab.ui.control.Button
        Add                   matlab.ui.control.Button
        UIDiscontinuityLabel  matlab.ui.control.Label
        UIDiscontinuity       matlab.ui.control.NumericEditField
        DoubleData            matlab.ui.control.CheckBox
        ExportData            matlab.ui.control.Button
        ExportDataListBox     matlab.ui.control.ListBox
        AmountOfFileLabel     matlab.ui.control.Label
        AmountOfFile          matlab.ui.control.NumericEditField
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        app_Filename
        app_Current_raw
        app_Voltage_raw
        app_Time_raw
        app_Discontinuity
        app_Drawing_number
    end
    
    methods (Access = private)
        
        function app_Start(app)
            if exist([app.FolderName.Value '\Data.mat'], 'file')
                load([app.FolderName.Value '\Data.mat'], '*')
                app.app_Filename = Filename;
                app.app_Current_raw = Current_raw;
                app.app_Voltage_raw = Voltage_raw;
                app.app_Time_raw = Time_raw;
                app.app_Discontinuity = Discontinuity;
            else
                app.app_Filename = ls([app.FolderName.Value '\*.csv']);
                [app.app_Current_raw, app.app_Voltage_raw, app.app_Time_raw, app.app_Discontinuity] = app_raed_raw(app);
            end
        end
        
        function app_Stop(app)
            Filename = app.app_Filename;
            Current_raw = app.app_Current_raw;
            Voltage_raw = app.app_Voltage_raw;
            Time_raw = app.app_Time_raw;
            Discontinuity = app.app_Discontinuity;
            save([app.FolderName.Value '\Data.mat'], ...
                'Filename', 'Current_raw', 'Voltage_raw', 'Time_raw', 'Discontinuity')
        end
        
        function [Current_raw, Voltage_raw, Time_raw, Discontinuity] = app_raed_raw(app)
            Amount_of_file = size(app.app_Filename, 1);
            Max_amount_of_data = 2000;
            Time_raw = zeros(Max_amount_of_data, Amount_of_file);
            Voltage_raw = zeros(Max_amount_of_data, Amount_of_file);
            Current_raw = zeros(Max_amount_of_data, Amount_of_file);
            for ii = 1:Amount_of_file
                [Time, Voltage, Current] = app_importfile(app, [app.FolderName.Value '\' app.app_Filename(ii, :)]);
                Data_length = length(Time) + 1;
                Time_raw(:, ii) = [Time(1:Data_length/2) ; ...
                    Inf(Max_amount_of_data - Data_length, 1) ; ...
                    Time(Data_length/2:end)];
                Voltage_raw(:, ii) = [Voltage(1:Data_length/2) ; ...
                    Inf(Max_amount_of_data - Data_length, 1) ; ...
                    Voltage(Data_length/2:end)];
                Current_raw(:, ii) = [Current(1:Data_length/2) ; ...
                    Inf(Max_amount_of_data - Data_length, 1) ; ...
                    Current(Data_length/2:end)];
            end
            cache = Current_raw;
            for ii =1:Amount_of_file
                inf_index = find(cache(:, ii) == inf);
                cache(inf_index, ii) = cache(inf_index(1) - 1, ii);
            end
            cache = abs(diff(cache));
            [~, Discontinuity] = max(cache);
        end
        
        function [Time, Voltage, Current] = app_importfile(~, filename)
            opts = delimitedTextImportOptions("NumVariables", 3);
            opts.DataLines = [1, Inf];
            opts.Delimiter = ",";
            opts.VariableNames = ["Time", "Voltage", "Current"];
            opts.VariableTypes = ["double", "double", "double"];
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            tbl = readtable(filename, opts);
            Time = tbl.Time;
            Voltage = tbl.Voltage;
            Current = tbl.Current;
        end
        
        function app_Drawing(app)
            app_Drawing_parameter_check(app)
            if ~isempty(app.app_Drawing_number)
                if length(app.app_Drawing_number) == 1
                    Cache = strrep(app.app_Filename(app.app_Drawing_number(1), :), 'set', 'SET');
                    Cache = strrep(Cache, '_', '-');
                    app.DataFiles.Value = Cache;
                    app.UIDiscontinuity.Value = (app.app_Discontinuity(app.app_Drawing_number(1)) - 1) * app.app_Voltage_raw(2, app.app_Drawing_number(1));
                else
                    app.DataFiles.Value = '';
                    app.UIDiscontinuity.Value = 0;
                end
            else
                app.DataFiles.Value = '';
                app.UIDiscontinuity.Value = 0;
            end
            cla(app.UIAxes,'reset')
            switch app.YAxisType.Value
                case 'Log'
                    semilogy(app.UIAxes, 0, 0, 'k')
                case 'Linear'
            end
            hold(app.UIAxes, 'on')
            for ii = app.app_Drawing_number
                plot(app.UIAxes, app.app_Voltage_raw(:, ii), abs(app.app_Current_raw(:, ii)), 'k')
            end
            for ii = app.app_Drawing_number
                scatter(app.UIAxes, app.app_Voltage_raw(app.app_Discontinuity(ii), ii), abs(app.app_Current_raw(app.app_Discontinuity(ii), ii)), 'r')
            end
            hold(app.UIAxes, 'off')
        end
        
        function app_Drawing_parameter_check(app)
            Amount_of_file = size(app.app_Filename, 1);
            switch app.Process.Value
                case 'ALL'
                    process = '2';
                case 'Delete'
                    process = 'set';
                case 'Normal'
                    process = '-';
                otherwise
                    process = ['-' app.Process.Value];
            end
            app.app_Drawing_number = zeros(1, Amount_of_file);
            jj = 0;
            for ii = 1:Amount_of_file
                if contains(app.app_Filename(ii, :), process)
                    jj = jj + 1;
                    app.app_Drawing_number(jj) = ii;
                end
            end
            app.AmountOfFile.Value = jj;
            app.app_Drawing_number = app.app_Drawing_number(1:jj);
            if jj > 0
                if app.SingleData.Value
                    if app.Start.Value > jj
                        app.Start.Value = jj;
                    end
                    app.Stop.Value = app.Start.Value;
                    app.app_Drawing_number = app.app_Drawing_number(app.Start.Value);
                elseif app.DoubleData.Value
                    if app.Start.Value + 1 > jj
                        app.Start.Value = app.Start.Value - 2;
                    end
                    app.Stop.Value = app.Start.Value + 1;
                    app.app_Drawing_number = app.app_Drawing_number(app.Start.Value:app.Stop.Value);
                else
                    if app.Start.Value > jj
                        app.Start.Value = jj;
                    end
                    if app.Stop.Value > jj
                        app.Stop.Value = jj;
                    end
                    app.app_Drawing_number = app.app_Drawing_number(app.Start.Value:app.Stop.Value);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FolderName.Value = uigetdir('C:\Users\Public\Documents\');
            app_Start(app)
            app_Drawing(app)
        end

        % Value changed function: Process, YAxisType
        function Redraw(app, event)
            if app.DoubleData.Value
                if contains(app.Process.Value, ["SET", "RESET", "Delete"])
                    app.Process.Value = 'Normal';
                end
            end
            app_Drawing(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app_Stop(app)
            delete(app)
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            if length(app.app_Drawing_number) == 1
                app.app_Filename(app.app_Drawing_number(1), :) = strrep(app.app_Filename(app.app_Drawing_number(1), :), 'SET', 'set');
                app.app_Filename(app.app_Drawing_number(1), :) = strrep(app.app_Filename(app.app_Drawing_number(1), :), '-', '_');
            end
            app_Drawing(app)
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            if length(app.app_Drawing_number) == 1
                app.app_Filename(app.app_Drawing_number(1), :) = strrep(app.app_Filename(app.app_Drawing_number(1), :),'set', 'SET');
                app.app_Filename(app.app_Drawing_number(1), :) = strrep(app.app_Filename(app.app_Drawing_number(1), :), '_', '-');
            end
            app_Drawing(app)
        end

        % Button pushed function: Modify
        function ModifyButtonPushed(app, event)
            if length(app.app_Drawing_number) == 1
                app.app_Discontinuity(app.app_Drawing_number(1)) = round(app.UIDiscontinuity.Value / app.app_Voltage_raw(2, app.app_Drawing_number(1))) + 1;
            end
            app_Drawing(app)
        end

        % Value changed function: DoubleData
        function DoubleDataValueChanged(app, event)
            if  contains(app.Process.Value, ["Normal", "ALL"])
                if app.DoubleData.Value && contains(app.app_Filename(app.app_Drawing_number(1), :), ["-SET", "_set"])
                    app.Start.Step = 2;
                    app.SingleData.Value = false;
                else
                    app.Start.Step = 1;
                    app.DoubleData.Value = false;
                end
            else
                app.DoubleData.Value = false;
            end
            app_Drawing(app)
        end

        % Button pushed function: ExportData
        function ExportDataButtonPushed(app, event)
            folderName = uigetdir('C:\Users\Public\Documents\');
            for ii = 1:length(app.ExportDataListBox.Value)
                switch app.ExportDataListBox.Value{ii}
                    case 'app.app_Filename'
                        index = '(app.app_Drawing_number, :)';
                    case 'app.app_Discontinuity'
                        index = '(app.app_Drawing_number) .* app.app_Voltage_raw(2, app.app_Drawing_number) - app.app_Voltage_raw(2, app.app_Drawing_number)';
                    otherwise
                        index = '(:, app.app_Drawing_number)';
                end
                eval(['writematrix(' app.ExportDataListBox.Value{ii} index ', ''' folderName '\' app.ExportDataListBox.Value{ii}(9:end) '.csv' '''' ')'])
            end
        end

        % Value changed function: SingleData
        function SingleDataValueChanged(app, event)
            if app.SingleData.Value
                app.Start.Step = 1;
                app.DoubleData.Value = false;
                app.Stop.Value = app.Start.Value;
            end
            app_Drawing(app)
        end

        % Value changed function: Start
        function StartValueChanged(app, event)
            if app.Start.Value > app.Stop.Value
                app.Stop.Value = app.Start.Value;
            end
            app_Drawing(app)
        end

        % Value changed function: Stop
        function StopValueChanged(app, event)
            if app.Start.Value > app.Stop.Value
                app.Start.Value = app.Stop.Value;
            end
            app_Drawing(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProcessLabel = uilabel(app.UIFigure);
            app.ProcessLabel.Text = '过程';
            app.Process = uidropdown(app.UIFigure);
            app.Process.Items = {'Normal', 'SET', 'RESET', 'Delete', 'ALL'};
            app.Process.ValueChangedFcn = createCallbackFcn(app, @Redraw, true);
            app.Process.Value = 'Normal';
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = 'Start';
            app.Start = uispinner(app.UIFigure);
            app.Start.Limits = [1 Inf];
            app.Start.ValueChangedFcn = createCallbackFcn(app, @StartValueChanged, true);
            app.Start.Value = 1;
            app.YAxisTypeLabel = uilabel(app.UIFigure);
            app.YAxisTypeLabel.Text = 'Y轴刻度模式';
            app.YAxisType = uidropdown(app.UIFigure);
            app.YAxisType.Items = {'Log', 'Linear'};
            app.YAxisType.ValueChangedFcn = createCallbackFcn(app, @Redraw, true);
            app.YAxisType.Value = 'Log';
            app.StopLabel = uilabel(app.UIFigure);
            app.StopLabel.Text = 'Stop';
            app.Stop = uispinner(app.UIFigure);
            app.Stop.Limits = [1 Inf];
            app.Stop.ValueChangedFcn = createCallbackFcn(app, @StopValueChanged, true);
            app.Stop.Value = 1;
            app.SingleData = uicheckbox(app.UIFigure);
            app.SingleData.ValueChangedFcn = createCallbackFcn(app, @SingleDataValueChanged, true);
            app.SingleData.Text = '单数据';
            app.SingleData.Value = true;
            app.FolderNameLabel = uilabel(app.UIFigure);
            app.FolderNameLabel.Text = '目标文件夹';
            app.FolderName = uieditfield(app.UIFigure, 'text');
            app.DataFilesLabel = uilabel(app.UIFigure);
            app.DataFilesLabel.Text = '数据文件';
            app.DataFiles = uieditfield(app.UIFigure, 'text');
            app.Modify = uibutton(app.UIFigure, 'push');
            app.Modify.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonPushed, true);
            app.Modify.Text = '更新突变电压';
            app.Delete = uibutton(app.UIFigure, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete';
            app.Add = uibutton(app.UIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Add';
            app.UIDiscontinuityLabel = uilabel(app.UIFigure);
            app.UIDiscontinuityLabel.Text = '突变电压';
            app.UIDiscontinuity = uieditfield(app.UIFigure, 'numeric');
            app.DoubleData = uicheckbox(app.UIFigure);
            app.DoubleData.ValueChangedFcn = createCallbackFcn(app, @DoubleDataValueChanged, true);
            app.DoubleData.Text = '双数据';
            app.ExportData = uibutton(app.UIFigure, 'push');
            app.ExportData.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportData.Text = '导出数据';
            app.ExportDataListBox = uilistbox(app.UIFigure);
            app.ExportDataListBox.Items = {'时间', '电压', '电流', '对应文件名', '突变电压'};
            app.ExportDataListBox.ItemsData = {'app.app_Time_raw', 'app.app_Voltage_raw', 'app.app_Current_raw', 'app.app_Filename', 'app.app_Discontinuity'};
            app.ExportDataListBox.Multiselect = 'on';
            app.ExportDataListBox.Value = {'app.app_Current_raw', 'app.app_Discontinuity'};
            app.AmountOfFileLabel = uilabel(app.UIFigure);
            app.AmountOfFileLabel.Text = '数据总量';
            app.AmountOfFile = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'V')
            ylabel(app.UIAxes, 'I')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Memristor_Raw_Data_Deal_With

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end